classdef Forcecal < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        ShowpicButton              matlab.ui.control.Button
        Image                      matlab.ui.control.Image
        VideodealButton            matlab.ui.control.Button
        DecTrainingButton          matlab.ui.control.Button
        DatadealButton             matlab.ui.control.Button
        ResultshowButton           matlab.ui.control.Button
        OutputEditField            matlab.ui.control.EditField
        OutputEditFieldLabel       matlab.ui.control.Label
        TrainStepmNEditField       matlab.ui.control.NumericEditField
        TrainStepmNEditFieldLabel  matlab.ui.control.Label
        TrainAimmNEditField        matlab.ui.control.NumericEditField
        TrainAimmNEditFieldLabel   matlab.ui.control.Label
        ClearButton                matlab.ui.control.Button
        SampleindexEditField       matlab.ui.control.NumericEditField
        SampleindexEditFieldLabel  matlab.ui.control.Label
        SavingpathTextArea         matlab.ui.control.TextArea
        SavingpathTextAreaLabel    matlab.ui.control.Label
        TrainingButton             matlab.ui.control.Button
        TrainControlButton         matlab.ui.control.Button
        TrainStartButton           matlab.ui.control.Button
        LocomotionTextArea         matlab.ui.control.TextArea
        LocomotionLabel            matlab.ui.control.Label
        AimforcemNEditField        matlab.ui.control.NumericEditField
        AimforcemNEditFieldLabel   matlab.ui.control.Label
        ControlButton              matlab.ui.control.Button
        ResetButton                matlab.ui.control.Button
        StopButton                 matlab.ui.control.Button
        StartButton                matlab.ui.control.Button
        ForceLabel                 matlab.ui.control.Label
        UIAxes                     matlab.ui.control.UIAxes
    end

    
properties (Access = private)
    isRunning = false;
    index = 1;
    timeData = zeros(1, 100); 
    forceData = zeros(1, 100);
    locomotionData=zeros(1, 100);
    isCondition1Met = false;
    isCondition2Met = false;
    isCondition3Met = false;
    isCondition4Met = false;
    isCondition5Met = false;
    isCondition6Met = false;
    isforcedecdone =  false;
    firsttimedec=true;
    forcedeccal=900;
    s
    m
    request
    locomotion=0;            
    isokcontrol=false;
    dealindex=1;
    existing_calculation_results={};
    end
 
methods (Access = private)
function keyPressFcn(app, event)
    switch event.Key
        case 'q'
            app.locomotion = 1; 
            app.LocomotionTextArea.Value='FWD';
        case 'w'
            app.locomotion = -1; 
            app.LocomotionTextArea.Value='BWD';
    end
end

function keyReleaseFcn(app, event)
    if ismember(event.Key, {'q', 'w'})
        app.locomotion = 0; 
        app.LocomotionTextArea.Value='STATIC';
    end
end

function startMeasurement(app)
    aimforce=app.AimforcemNEditField.Value;
    app.isRunning = true;
    app.index = 1;
    app.timeData = zeros(1, 100);
    app.forceData = zeros(1, 100);
    app.isCondition1Met = false;
    app.isCondition2Met = false;
    tic;
    connect2motor(app.m, "MODE_V 0");
    connect2motor(app.m, "V_ACC_DEC 300 800");
    connect2motor(app.m, "V_ABS 0 -250");
    savedir=string(strcat(app.SavingpathTextArea.Value,'\S',num2str(app.SampleindexEditField.Value),'\'));
    if ~exist(string(strcat(app.SavingpathTextArea.Value,'\S',num2str(app.SampleindexEditField.Value))),"dir")
        mkdir(string(strcat(app.SavingpathTextArea.Value,'\S',num2str(app.SampleindexEditField.Value))));
    end
    videoFilename = strcat(savedir,'recorded_video.avi')
    v = VideoWriter(videoFilename);
    v.FrameRate = 5; 
    open(v);

    while app.isRunning

        elapsedTime = toc;
        write(app.s, app.request, "uint8");
        outdec = read(app.s, 9, "uint8");
        if length(outdec) < 9
            continue;
        else
            outhex = dec2hex(outdec);
            outstr = reshape(outhex.', 1, []);
            Forcedata = hex2dec(outstr(7:14)) * 0.1;
            if Forcedata > 5000
                continue; 
            end
            app.timeData(app.index) = elapsedTime;
            app.forceData(app.index) = Forcedata;
            app.locomotionData(app.index)=app.locomotion;
            plot(app.UIAxes, app.timeData(1:app.index), app.forceData(1:app.index), 'b-');
            app.ForceLabel.Text = sprintf('Force: %.1f mN', Forcedata);
            drawnow;
            app.index = app.index + 1;
            if ~app.isCondition1Met && Forcedata > aimforce-10
                try
                    connect2motor(app.m, "V_ABS 0 -50");
                    app.OutputEditField.Value="Plat A";
                    app.isCondition1Met = true;
                catch
                    app.OutputEditField.Value="Wrong";
                end
            end
            if ~app.isCondition2Met && Forcedata > aimforce-5
                try
                    connect2motor(app.m, "HALT_ONE 0");
                    app.OutputEditField.Value="Plat B";
                    app.isCondition2Met = true;
                catch
                    app.OutputEditField.Value="Wrong";
                end
            end
            if app.isCondition3Met && Forcedata>aimforce-5
                connect2motor(app.m, "V_ABS 0 50");
            end
            if app.isCondition3Met && Forcedata<aimforce+5
                connect2motor(app.m, "V_ABS 0 -50");
            end
            larvaframe=screencapture(0,[0,-80,1200,1080]);
            writeVideo(v, larvaframe); 
            pause(0.01);
        end
    end
       close(v);
end

        
function stopMeasurement(app)
    app.isRunning = false;
    app.isCondition1Met=false;
    app.isCondition2Met=false;
    app.isCondition3Met=false;
    app.isCondition4Met=false;
    app.isCondition5Met=false;
    app.isCondition6Met=false;
    timeData = app.timeData;
    forceData = app.forceData;
    locomotionData = app.locomotionData;
    savedir=string(strcat(app.SavingpathTextArea.Value,'\S',num2str(app.SampleindexEditField.Value),'\time_force_locomotiondata'));
    if ~exist(string(strcat(app.SavingpathTextArea.Value,'\S',num2str(app.SampleindexEditField.Value))),"dir")
        mkdir(string(strcat(app.SavingpathTextArea.Value,'\S',num2str(app.SampleindexEditField.Value))));
    end
    save(savedir, 'timeData', 'forceData', 'locomotionData');
    connect2motor(app.m,"V_STOP 0");
    app.OutputEditField.Value="PROGRAM STOP";
end


function trainMeasurement(app)
    aimforce=app.TrainAimmNEditField.Value;
    app.isRunning = true;
    app.index = 1;
    app.timeData = zeros(1, 100);
    app.forceData = zeros(1, 100);
    app.isCondition1Met = false;
    app.isCondition2Met = false;
    tic;
    connect2motor(app.m, "MODE_V 0");
    connect2motor(app.m, "V_ACC_DEC 500 8000");
    connect2motor(app.m, "V_ABS 0 -350");

    while app.isRunning
        elapsedTime = toc;
        write(app.s, app.request, "uint8");
        outdec = read(app.s, 9, "uint8");
        if length(outdec) < 9
            continue;
        else
            outhex = dec2hex(outdec);
            outstr = reshape(outhex.', 1, []);
            Forcedata = hex2dec(outstr(7:14)) * 0.1;

            if Forcedata > 500
                continue;
            end
            app.timeData(app.index) = elapsedTime;
            app.forceData(app.index) = Forcedata;
            app.locomotionData(app.index)=app.locomotion;
            plot(app.UIAxes, app.timeData(1:app.index), app.forceData(1:app.index), 'b-');
            app.ForceLabel.Text = sprintf('Force: %.1f mN', Forcedata);
            drawnow;
            app.index = app.index + 1;
            if ~app.isCondition1Met && Forcedata > aimforce-25
                try
                    connect2motor(app.m, "V_ABS 0 -50");
                    app.isCondition1Met = true;
                end
            end
            if ~app.isCondition2Met && Forcedata > aimforce-10
                try
                    connect2motor(app.m, "HALT_ONE 0");
                    app.isCondition2Met = true;
                end
            end
            if app.isCondition4Met && Forcedata>aimforce-10 
                connect2motor(app.m, "V_ABS 0 50");
            end
            if app.isCondition4Met&&Forcedata<aimforce+5&&Forcedata>aimforce-5
                connect2motor(app.m, "HALT_ONE 0");
            end
            if app.isCondition4Met && Forcedata<aimforce+10
                connect2motor(app.m, "V_ABS 0 -50");
            end
            if app.isCondition5Met && Forcedata>app.forcedeccal-app.TrainStepmNEditField.Value && app.firsttimedec
                connect2motor(app.m, "V_ABS 0 50");
                app.firsttimedec=false;
                app.OutputEditField.Value="Downing";
            end
            if app.isCondition5Met && Forcedata<app.forcedeccal-app.TrainStepmNEditField.Value &&~app.firsttimedec
                connect2motor(app.m, "HALT_ONE 0");
                app.firsttimedec=true;
                app.OutputEditField.Value="Arrived";
                app.isCondition5Met=false;
            end     
           if app.isCondition6Met && Forcedata>app.forcedeccal-app.TrainStepmNEditField.Value && app.firsttimedec
                connect2motor(app.m, "V_ABS 0 -50");
                app.firsttimedec=false;
                app.OutputEditField.Value="Downing";
            end
            if app.isCondition6Met && Forcedata<app.forcedeccal-app.TrainStepmNEditField.Value &&~app.firsttimedec
                connect2motor(app.m, "HALT_ONE 0");
                app.firsttimedec=true;
                app.OutputEditField.Value="Arrived";
                app.isCondition6Met=false;
            end  
            pause(0.1);
        end
    end
end
end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            try
                app.m = serialport('COM3', 115200, 'DataBits', 8, 'StopBits', 1, 'Parity', 'None', 'Timeout', 1);
                app.s = serialport('COM4', 9600, 'DataBits', 8, 'StopBits', 1, 'Parity', 'None', 'Timeout', 1);
                app.request = uint8(hex2dec(['01'; '03'; '00'; '50'; '00'; '02'; 'C4'; '1A']));
                app.OutputEditField.Value="COM ready";
                app.UIFigure.KeyPressFcn = @(src, event) keyPressFcn(app, event);
                app.UIFigure.KeyReleaseFcn = @(src, event) keyReleaseFcn(app, event);
            catch
               app.OutputEditField.Value="COM wrong";
            end
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            startMeasurement(app);
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            
            stopMeasurement(app);
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
    aimforce=1;
    app.isRunning = true;
    app.isCondition1Met = false;
    app.isCondition2Met = false;
    connect2motor(app.m, "MODE_V 0");
    connect2motor(app.m, "V_ACC_DEC 500 800");
    connect2motor(app.m, "V_ABS 0 300");
    pause(2)
    connect2motor(app.m, "HALT_ONE 0");
        end

        % Button pushed function: ControlButton
        function ControlButtonPushed(app, event)
            app.isCondition3Met = true;
            app.isCondition1Met=true;
            app.isCondition2Met=true;
            app.isCondition5Met=false;
        end

        % Button pushed function: TrainStartButton
        function TrainStartButtonPushed(app, event)
            trainMeasurement(app);
        end

        % Button pushed function: TrainControlButton
        function TrainControlButtonPushed(app, event)
            app.isCondition4Met = true;
        end

        % Button pushed function: TrainingButton
        function TrainingButtonPushed(app, event)
            app.isCondition4Met = false;
            app.isCondition5Met = true;
            app.forcedeccal=app.forceData(app.index-1);
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            cla(app.UIAxes)
        end

        % Button pushed function: ResultshowButton
        function ResultshowButtonPushed(app, event)
[file, path] = uigetfile('*.mat', 'Select the MAT-file');
if isequal(file, 0) || isequal(path, 0)
   disp('User pressed cancel');
else
   data = load(fullfile(path, file));
   app.timeData = data.timeData;
   app.forceData = data.forceData;
   app.locomotionData = data.locomotionData;
   minLength = min([length(app.timeData), length(app.forceData), length(app.locomotionData)]);
   app.timeData = app.timeData(1:minLength);
   app.forceData = app.forceData(1:minLength);
   app.locomotionData = app.locomotionData(1:minLength);
   plot(app.UIAxes, app.timeData, app.forceData, 'b-');
   app.UIAxes.NextPlot = 'add';
   plot(app.UIAxes, app.timeData, app.locomotionData, 'r-');
   app.UIAxes.NextPlot = 'replace';
end

        end

        % Button pushed function: DatadealButton
        function DatadealButtonPushed(app, event)

    [file, path] = uigetfile('*.mat', 'Select the MAT-file');
    if isequal(file, 0) || isequal(path, 0)
        disp('User canceled file selection');
        return;
    end
    data = load(fullfile(path, file));
    app.timeData = data.timeData(120:end);
    app.timeData=app.timeData-app.timeData(1);
    app.forceData = data.forceData(120:end);
    app.locomotionData = data.locomotionData(120:end);  
    t = app.timeData;
    force = app.forceData;
    newall = app.locomotionData;
    tempindex = find(force > 1, 1, 'first');
    t=t(1:end-tempindex);
    force=force(tempindex:end);
    newall=newall(tempindex:end);
    minLength = min([length(t), length(force), length(newall)]);
    t = t(1:minLength);
    force = force(1:minLength);
    newall = newall(1:minLength);
    Timelimit = 2 * 60 * 30;
    force = limit_matrix_rows(force, Timelimit);
    newall = limit_matrix_rows(newall, Timelimit);
    total_time_seconds = t(end) - t(1);
    forward_idx = find(newall(2:end) == 1 & newall(1:end-1) ~= 1) + 1;
    forward_times = t(forward_idx); 
    relative_forward_times = (forward_times - t(1)) / total_time_seconds; 
    backward_idx = find(newall(2:end) == -1 & newall(1:end-1) ~= -1) + 1;
    backward_times = t(backward_idx); 
    relative_backward_times = (backward_times - t(1)) / total_time_seconds;
    forward_frequency = numel(forward_idx) / total_time_seconds;
    backward_frequency = numel(backward_idx) / total_time_seconds;
    temp = force;
    [peaks, peak_locs, ~, p] = findpeaks(temp, 'MinPeakDistance', 1 * 30, 'MinPeakProminence', 5);
    peak_time = t(peak_locs);
    smoothed_data = smoothdata(temp, 'movmean', 900);
    cla(app.UIAxes);
    hold(app.UIAxes, 'on');
    scatter(app.UIAxes, t(newall == 1), newall(newall == 1), [], 'red', '.', 'LineWidth', 0.5, 'MarkerFaceColor', 'none');
    scatter(app.UIAxes, t(newall == -1), newall(newall == -1), [], 'blue', '.', 'LineWidth', 0.5, 'MarkerFaceColor', 'none');
    plot(app.UIAxes, t, force, 'black', 'LineWidth', 2);
    plot(app.UIAxes, t(peak_locs), peaks, 'Color', 'black', 'Marker', 'o', 'MarkerSize', 12, 'MarkerFaceColor', 'none', 'MarkerEdgeColor', 'red');
    xlabel(app.UIAxes, 'Time (s)');
    ylabel(app.UIAxes, 'Force (mN)');
    ylim(app.UIAxes, [-2, max(force)+5]);
    xlim(app.UIAxes, [0, 120]);
    title(app.UIAxes, 'Force and Locomotion');
    hold(app.UIAxes, 'off');
    time_diff = diff(peak_time);
    peak_frequency = 1 ./ time_diff;
    average_frequency = mean(peak_frequency);
    max_intervals = max(time_diff);
    min_intervals = min(time_diff);
    maxforceadd = max(diff(peaks));
    maxforcedec = min(diff(peaks));
    num_peaks = numel(peaks);
    start_time = min(peak_time);
    end_time = max(peak_time);
    duration = end_time - start_time;
    peak_diff = diff(peaks);
    peak_change_rate = peak_diff ./ time_diff;
    num_rising = sum(peak_change_rate > 0.1); 
    num_falling = sum(peak_change_rate < -0.1); 
    indicators_table = table(forward_frequency,backward_frequency,average_frequency, max_intervals, min_intervals, maxforceadd, maxforcedec, num_peaks, start_time, end_time, duration, num_rising, num_falling);
    upper_folder_path = fileparts(fileparts(path));
    [~, grandparent_folder_name] = fileparts(upper_folder_path); 
    excel_file_path = fullfile(upper_folder_path, [grandparent_folder_name, '.xls']);
    if exist(excel_file_path, 'file') == 2
        existing_data = readtable(excel_file_path);
        if ~isequal(existing_data.Properties.VariableNames, indicators_table.Properties.VariableNames)
            common_columns = intersect(existing_data.Properties.VariableNames, indicators_table.Properties.VariableNames);
            missing_columns_existing = setdiff(existing_data.Properties.VariableNames, common_columns);
            missing_columns_new = setdiff(indicators_table.Properties.VariableNames, common_columns);
            for col = missing_columns_existing
                indicators_table.(col{1}) = NaN(height(indicators_table), 1);
            end
            for col = missing_columns_new
                existing_data.(col{1}) = NaN(height(existing_data), 1);
            end
            indicators_table = indicators_table(:, existing_data.Properties.VariableNames);
        end
        if any(strcmp(existing_data.Properties.VariableNames, 'Path'))
            combined_data = [existing_data; indicators_table];
        else
            path_info = string(upper_folder_path);
            tempindicators_table = [table(path_info, 'VariableNames', {'Path'}), indicators_table];
            combined_data = [existing_data; tempindicators_table];
        end
    else
        path_info = string(upper_folder_path);
        combined_data = [table(path_info, 'VariableNames', {'Path'}), indicators_table];
    end
    writetable(combined_data, excel_file_path);
    [~, filename, ~] = fileparts(file);  
    png_file_path = fullfile(path, [filename, '.png']); 
    exportgraphics(app.UIAxes, png_file_path, 'Resolution', 300);
    disp(['Data processed and saved at: ', excel_file_path]);
fwd_start_idx = find(newall(2:end) == 1 & newall(1:end-1) ~= 1) + 1;
fwd_end_idx = find(newall(1:end-1) == 1 & newall(2:end) ~= 1);
bwd_start_idx = find(newall(2:end) == -1 & newall(1:end-1) ~= -1) + 1;
bwd_end_idx = find(newall(1:end-1) == -1 & newall(2:end) ~= -1);
fwdforcechange = zeros(length(fwd_start_idx), 1);
for i = 1:length(fwd_start_idx)
    fwdforcechange(i) = force(fwd_end_idx(i)) - force(fwd_start_idx(i));
end
bwdforcechange = zeros(length(bwd_start_idx), 1);
for i = 1:length(bwd_start_idx)
    bwdforcechange(i) = force(bwd_end_idx(i)) - force(bwd_start_idx(i));
end
mat_file_path = fullfile(upper_folder_path, [grandparent_folder_name, '.mat']);
new_sn_name = ['S', num2str(app.dealindex)];  
new_sn_results = {
    'forward_frequency', forward_frequency;
    'backward_frequency', backward_frequency;
    'relative_forward_times', relative_forward_times;
    'relative_backward_times', relative_backward_times;
    'fwdforcechange', fwdforcechange;
    'bwdforcechange', bwdforcechange;
    'average_frequency', average_frequency;
    'max_intervals', max_intervals;
    'min_intervals', min_intervals;
    'maxforceadd', maxforceadd;
    'maxforcedec', maxforcedec;
    'num_peaks', num_peaks;
    'start_time', start_time;
    'end_time', end_time;
    'duration', duration;
    'num_rising', num_rising;
    'num_falling', num_falling;

};
app.existing_calculation_results = [app.existing_calculation_results; {new_sn_name, new_sn_results}];
updated_calculation_results=app.existing_calculation_results;
save(mat_file_path, 'updated_calculation_results');
disp(['Results appended and saved in MAT file: ', mat_file_path]);
app.dealindex=app.dealindex+1;


        end

        % Button pushed function: DecTrainingButton
        function DecTrainingButtonPushed(app, event)
                app.isCondition4Met = false;
            app.isCondition5Met = false;
            app.isCondition6Met = true;
            app.forcedeccal=app.forceData(app.index-1);
        end

        % Callback function
        function SmacqButtonPushed(app, event)
loadlibrary('USB3000.dll','USB3000.h') 
libfunctions USB3000 -full

DevIndex = int32(0);                      
ChanON = uint8(1);
ChanOFF = uint8(0);
AiRange = single(2.56);
AiSampleMode = uint8(0);
AiConnectType = uint8(1);
SamplePeriod = uint32(100000);
AiTrigSource = uint8(0);
AiConvSource = uint8(0);
Points = uint32(1000);
TimeOut = uint32(4000);
Data = zeros(1,160000);                                 
DataPtr = libpointer('singlePtr',Data);
calllib('USB3000','USB3OpenDevice',DevIndex)                                                                                            
calllib('USB3000','USB3ReadDevcieSN',DevIndex)                           
calllib('USB3000','USB3ReadDevcieModel',DevIndex)
for i=1:8
    calllib('USB3000','SetUSB3AiChanSel',DevIndex,i - 1 ,ChanOFF)        
end
for i= 1:1
    calllib('USB3000','SetUSB3AiChanSel',DevIndex,i - 1 ,ChanON)           
    calllib('USB3000','SetUSB3AiRange',DevIndex,i - 1 ,AiRange)           
end 
calllib('USB3000','SetUSB3AiSampleMode',DevIndex,AiSampleMode)             
calllib('USB3000','SetUSB3AiConnectType',DevIndex,AiConnectType)                                                                               
calllib('USB3000','SetUSB3AiSampleRate',DevIndex,SamplePeriod)            
calllib('USB3000','SetUSB3AiTrigSource',DevIndex,AiTrigSource)                                                                                                                                                  
calllib('USB3000','SetUSB3AiConvSource',DevIndex,AiConvSource)                                                                     
calllib('USB3000','SetUSB3ClrAiFifo',DevIndex)                             
calllib('USB3000','SetUSB3AiSoftTrig',DevIndex)                           
for j =1:100
Fifo = calllib('USB3000','USB3GetAi',DevIndex,Points,DataPtr,TimeOut)
end                                                           
Data = get(DataPtr,'Value'); 
pause(2)                                                               
myData = zeros(1,1000);                                                     
myData = Data(1:1000);                           
plot(app.UIAxes,myData)                                                                               
calllib('USB3000','SetUSB3ClrAiTrigger',DevIndex)                         
calllib('USB3000','SetUSB3ClrAiFifo',DevIndex)                          
calllib('USB3000','USB3CloseDevice',DevIndex)                         
clear all;
unloadlibrary('USB3000')
        end

        % Button pushed function: VideodealButton
        function VideodealButtonPushed(app, event)
[file, path] = uigetfile('*.avi', 'Select the MAT-file');
if isequal(file, 0) || isequal(path, 0)
     disp('User canceled file selection');
     return;
end
%
pixelspermm=32.0624;
load([path,'\time_force_locomotiondata.mat'])
videoFile =fullfile(path, file);
video = VideoReader(videoFile);
outputVideo = VideoWriter('output_with_angle_analysis.avi');
open(outputVideo);
trajectory = [];
angles = [];
prevHead = [];
prevTail = [];
pixelsPerMM = 32.0624;
prevCentroid = [];
frameRate = length(timeData)/max(timeData); 
speeds = [];

while hasFrame(video)
    if isempty(prevCentroid)
        isFirstFrame=true;
    end
    frame = readFrame(video);
    grayFrame = 255 - rgb2gray(frame);
    binaryFrame = imbinarize(grayFrame, "global");
    binaryFrame = bwareaopen(binaryFrame, 50);
    se = strel('disk', 5);
    binaryFrame = imclose(binaryFrame, se);

    stats = regionprops(binaryFrame, 'Centroid', 'Orientation', 'PixelList');
    if ~isempty(stats)
        centroid = stats(1).Centroid;
        trajectory = [trajectory; centroid];
        if ~isempty(prevCentroid)
            displacement = norm(centroid - prevCentroid) / pixelsPerMM;
            speed = displacement * frameRate; % mm/s
        else
            speed = 0;
        end
        speeds = [speeds; speed];
        prevCentroid = centroid;
        orientation = stats(1).Orientation;
        perimeter = bwperim(binaryFrame);
        [edgeY, edgeX] = find(perimeter);
        edgePoints = [edgeX, edgeY];
CC = bwconncomp(binaryFrame); 
numPixels = cellfun(@numel, CC.PixelIdxList); 
[~, idx] = max(numPixels);
cleanBinaryFrame = false(size(binaryFrame));
cleanBinaryFrame(CC.PixelIdxList{idx}) = true;
perimeter = bwperim(cleanBinaryFrame); 
[edgeY, edgeX] = find(perimeter); 
edgePoints = [edgeX, edgeY];
        distances = vecnorm(edgePoints - centroid, 2, 2);
        [~, maxIdx] = max(distances);
        head = edgePoints(maxIdx, :);
        reldis = norm(centroid - head);
        remainingPoints = [];
        for i = 1:size(edgePoints, 1)
            point = edgePoints(i, :);
            if norm(point - head) > reldis
                remainingPoints = [remainingPoints; point];
            end
        end
        if ~isempty(remainingPoints)
            distancesToCentroid = vecnorm(remainingPoints - centroid, 2, 2);
            [~, maxTailIdx] = max(distancesToCentroid);
            tail = remainingPoints(maxTailIdx, :);
        else
            tail = centroid;
        end

        if ~isempty(prevHead) && ~isempty(prevTail)
            distHeadToPrevHead = norm(head - prevHead);
            distHeadToPrevTail = norm(head - prevTail);
            distTailToPrevHead = norm(tail - prevHead);
            distTailToPrevTail = norm(tail - prevTail);
            
            if distHeadToPrevTail + distTailToPrevHead < distHeadToPrevHead + distTailToPrevTail
                temp = head;
                head = tail;
                tail = temp;
            end
        end
        vectorHead = head - centroid;
        vectortail = centroid-tail;
        angleDiff = atan2d(vectorHead(2), vectorHead(1))-atan2d(vectortail(2), vectortail(1));
        angles = [angles; angleDiff];
    end
    figure(1);
    imshow(frame);
    hold on;
    if ~isempty(trajectory)
        plot(trajectory(:,1), trajectory(:,2), 'r-', 'LineWidth', 2);
    end
    if exist('head', 'var') && exist('tail', 'var')
        plot(head(1), head(2), 'bo', 'MarkerSize', 10, 'MarkerFaceColor', 'b');
        plot(tail(1), tail(2), 'go', 'MarkerSize', 10, 'MarkerFaceColor', 'g');
        text(centroid(1), centroid(2), sprintf('Angle: %.2f', angleDiff), 'Color', 'yellow');
        text(centroid(1), centroid(2)-20, sprintf('speed: %.2f', speed), 'Color', 'red');
    end
    hold off;
    if isFirstFrame
        userChoice = questdlg('Do you want to swap head and tail?', ...
            'Swap Head and Tail', 'Yes', 'No', 'No');
        if strcmp(userChoice, 'Yes')
            temp = head;
            head = tail;
            tail = temp;
            figure(1);
            imshow(frame);
            hold on;
            if ~isempty(trajectory)
                plot(trajectory(:,1), trajectory(:,2), 'r-', 'LineWidth', 2);
            end
            plot(head(1), head(2), 'bo', 'MarkerSize', 10, 'MarkerFaceColor', 'b');
            plot(tail(1), tail(2), 'go', 'MarkerSize', 10, 'MarkerFaceColor', 'g');
            text(centroid(1), centroid(2), sprintf('Speed: %.2f', speed), 'Color', 'red');
            hold off;
        end
    end

        prevHead = head;
        prevTail = tail;
    isFirstFrame = false;
    frameWithAnalysis = getframe(gca);
    writeVideo(outputVideo, frameWithAnalysis.cdata);
end
smoothSpeeds = smoothdata(speeds, 'movmean', 5); 
timeData = timeData(1:length(smoothSpeeds));
forceData = forceData(1:length(smoothSpeeds));
locomotionData = locomotionData(1:length(smoothSpeeds));

figure('Position', [100, 100, 1920, 1080]); 
subplot(3, 1, 1);
plot(timeData, forceData, 'b-', 'LineWidth', 1.5);
xlabel('Time');
ylabel('Force');
title('Force over Time');
grid on;
subplot(3, 1, 2);
plot(timeData, smoothSpeeds, 'r-', 'LineWidth', 1.5);
xlabel('Time');
ylabel('Speed (mm/s)');
title('Smoothed Centroid Speed over Time');
grid on;
subplot(3, 1, 3);
plot(timeData, angles, 'g-', 'LineWidth', 1.5);
xlabel('Time');
ylabel('Angle (degrees)');
title('Angle Variation over Time');
grid on;
sgtitle('Analysis Plots');
saveas(gcf, fullfile(path, 'analysis_plots.png'));
saveas(gcf, fullfile(path, 'analysis_plots.fig'));

        end

        % Button pushed function: ShowpicButton
        function ShowpicButtonPushed(app, event)
            larvaframe=screencapture(0,[0,-80,1200,1080]);
            app.Image.ImageSource=larvaframe;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Larva comperssion')
            xlabel(app.UIAxes, 'Time(s)')
            ylabel(app.UIAxes, 'Force(mN)')
            zlabel(app.UIAxes, 'Z')
            app.ForceLabel = uilabel(app.UIFigure);
            app.ForceLabel.Text = 'Force';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.ControlButton = uibutton(app.UIFigure, 'push');
            app.ControlButton.ButtonPushedFcn = createCallbackFcn(app, @ControlButtonPushed, true);
            app.ControlButton.Text = 'Control';
            app.AimforcemNEditFieldLabel = uilabel(app.UIFigure);
            app.AimforcemNEditFieldLabel.Text = 'Aim force(mN)';
            app.AimforcemNEditField = uieditfield(app.UIFigure, 'numeric');
            app.AimforcemNEditField.Value = 25;
            app.LocomotionLabel = uilabel(app.UIFigure);
            app.LocomotionLabel.Text = 'Locomotion:';
            app.LocomotionTextArea = uitextarea(app.UIFigure);
            app.LocomotionTextArea.Value = {'STATIC'};
            app.TrainStartButton = uibutton(app.UIFigure, 'push');
            app.TrainStartButton.ButtonPushedFcn = createCallbackFcn(app, @TrainStartButtonPushed, true);
            app.TrainStartButton.Text = 'TrainStart';
            app.TrainControlButton = uibutton(app.UIFigure, 'push');
            app.TrainControlButton.ButtonPushedFcn = createCallbackFcn(app, @TrainControlButtonPushed, true);
            app.TrainControlButton.Text = 'TrainControl';
            app.TrainingButton = uibutton(app.UIFigure, 'push');
            app.TrainingButton.ButtonPushedFcn = createCallbackFcn(app, @TrainingButtonPushed, true);
            app.TrainingButton.Text = 'Training';
            app.SavingpathTextAreaLabel = uilabel(app.UIFigure);
            app.SavingpathTextAreaLabel.Text = 'Saving path';
            app.SavingpathTextArea = uitextarea(app.UIFigure);
            app.SavingpathTextArea.Value = {'D:\dym\Force_larva\Data\CS\Temp'};
            app.SampleindexEditFieldLabel = uilabel(app.UIFigure);
            app.SampleindexEditFieldLabel.Text = 'Sample index';
            app.SampleindexEditField = uieditfield(app.UIFigure, 'numeric');
            app.SampleindexEditField.Value = 1;
            app.ClearButton = uibutton(app.UIFigure, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.TrainAimmNEditFieldLabel = uilabel(app.UIFigure);
            app.TrainAimmNEditFieldLabel.Text = 'Train Aim (mN)';
            app.TrainAimmNEditField = uieditfield(app.UIFigure, 'numeric');
            app.TrainAimmNEditField.Value = 50;
            app.TrainStepmNEditFieldLabel = uilabel(app.UIFigure);
            app.TrainStepmNEditFieldLabel.Text = 'Train Step (mN)';
            app.TrainStepmNEditField = uieditfield(app.UIFigure, 'numeric');
            app.TrainStepmNEditField.Value = 5;
            app.OutputEditFieldLabel = uilabel(app.UIFigure);
            app.OutputEditFieldLabel.Text = 'Output';
            app.OutputEditField = uieditfield(app.UIFigure, 'text');
            app.ResultshowButton = uibutton(app.UIFigure, 'push');
            app.ResultshowButton.ButtonPushedFcn = createCallbackFcn(app, @ResultshowButtonPushed, true);
            app.ResultshowButton.Text = 'Result show';
            app.DatadealButton = uibutton(app.UIFigure, 'push');
            app.DatadealButton.ButtonPushedFcn = createCallbackFcn(app, @DatadealButtonPushed, true);
            app.DatadealButton.Text = 'Data deal';
            app.DecTrainingButton = uibutton(app.UIFigure, 'push');
            app.DecTrainingButton.ButtonPushedFcn = createCallbackFcn(app, @DecTrainingButtonPushed, true);
            app.DecTrainingButton.Text = 'DecTraining';
            app.VideodealButton = uibutton(app.UIFigure, 'push');
            app.VideodealButton.ButtonPushedFcn = createCallbackFcn(app, @VideodealButtonPushed, true);
            app.VideodealButton.Text = 'Video deal';
            app.Image = uiimage(app.UIFigure);
            app.ShowpicButton = uibutton(app.UIFigure, 'push');
            app.ShowpicButton.ButtonPushedFcn = createCallbackFcn(app, @ShowpicButtonPushed, true);
            app.ShowpicButton.Text = 'Showpic';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Forcecal

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end